classdef FFT < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        sampling_rate        matlab.ui.control.NumericEditField
        Label_5              matlab.ui.control.Label
        single_liner_insert  matlab.ui.control.StateButton
        window_weight_2      matlab.ui.control.DropDown
        Label_4              matlab.ui.control.Label
        window_num_2         matlab.ui.control.NumericEditField
        EditField_8Label     matlab.ui.control.Label
        window_num_1         matlab.ui.control.NumericEditField
        EditField_7Label     matlab.ui.control.Label
        FFT_point_knob       matlab.ui.control.DiscreteKnob
        FFTKnobLabel         matlab.ui.control.Label
        noise_knob           matlab.ui.control.Knob
        mVLabel              matlab.ui.control.Label
        output_text          matlab.ui.control.TextArea
        Label_3              matlab.ui.control.Label
        APFFTButton          matlab.ui.control.StateButton
        window_weight_1      matlab.ui.control.DropDown
        Label                matlab.ui.control.Label
        activate_2           matlab.ui.control.StateButton
        activate_6           matlab.ui.control.StateButton
        activate_5           matlab.ui.control.StateButton
        activate_4           matlab.ui.control.StateButton
        activate_3           matlab.ui.control.StateButton
        activate_1           matlab.ui.control.StateButton
        Phase_num_6          matlab.ui.control.NumericEditField
        EditField_6Label     matlab.ui.control.Label
        Phase_num_5          matlab.ui.control.NumericEditField
        EditField_5Label     matlab.ui.control.Label
        Phase_num_4          matlab.ui.control.NumericEditField
        EditField_4Label     matlab.ui.control.Label
        Phase_num_3          matlab.ui.control.NumericEditField
        EditField_3Label     matlab.ui.control.Label
        Phase_num_2          matlab.ui.control.NumericEditField
        EditField_2Label     matlab.ui.control.Label
        Phase_num_1          matlab.ui.control.NumericEditField
        EditFieldLabel       matlab.ui.control.Label
        Freq_num_6           matlab.ui.control.NumericEditField
        HzEditField_6Label   matlab.ui.control.Label
        Freq_num_5           matlab.ui.control.NumericEditField
        HzEditField_5Label   matlab.ui.control.Label
        Vpp_num_6            matlab.ui.control.NumericEditField
        VEditField_6Label    matlab.ui.control.Label
        Vpp_num_5            matlab.ui.control.NumericEditField
        VEditField_5Label    matlab.ui.control.Label
        Freq_num_4           matlab.ui.control.NumericEditField
        HzEditField_4Label   matlab.ui.control.Label
        Freq_num_3           matlab.ui.control.NumericEditField
        HzEditField_3Label   matlab.ui.control.Label
        Freq_num_2           matlab.ui.control.NumericEditField
        HzEditField_2Label   matlab.ui.control.Label
        Freq_num_1           matlab.ui.control.NumericEditField
        HzEditFieldLabel     matlab.ui.control.Label
        Vpp_num_4            matlab.ui.control.NumericEditField
        VEditField_4Label    matlab.ui.control.Label
        Vpp_num_3            matlab.ui.control.NumericEditField
        VEditField_3Label    matlab.ui.control.Label
        Vpp_num_2            matlab.ui.control.NumericEditField
        VEditField_2Label    matlab.ui.control.Label
        Vpp_num_1            matlab.ui.control.NumericEditField
        VEditFieldLabel      matlab.ui.control.Label
        Calculate_begin      matlab.ui.control.Button
        Window_weight_map    matlab.ui.control.UIAxes
        Raw_signal_map       matlab.ui.control.UIAxes
        Phase_map            matlab.ui.control.UIAxes
        Freq_map             matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        output_string_buff % 输出文本
        NPT% Description
    end
    
    methods (Access = private)
        % 自定义main函数进行处理
        function user_main(app)
            input_signal = signal_generate(app); % 生成目标信号
            output_remain(app,'信号生成成功');
            point_str = sprintf('信号采样点数:%.0f',str2double(app.FFT_point_knob.Value));
            output_remain(app,point_str);
            signal_len = 0:(str2double(app.FFT_point_knob.Value) - 1);
            plot(app.Raw_signal_map,signal_len,input_signal); % 绘制原始信号图像
            output_remain(app,'原始信号图像绘制完成');
            if app.single_liner_insert.Value
               output_remain(app,'单线性插值:开启');
               insert_signal = signal_insert(app,input_signal); % 进行中点插值
               insert_signal = window_add(app,insert_signal); % 进行加窗运算
               fft_cal(app,insert_signal); % 计算FFT(频谱和相位谱)
            else
               output_remain(app,'单线性插值:关闭');
               input_signal = window_add(app,input_signal); % 进行加窗运算
               fft_cal(app,input_signal); % 计算FFT(频谱和相位谱)
            end
        end
        
        function raw_signal = signal_generate(app)
            % 信号生成函数
            raw_signal = zeros(1,app.NPT); % 存储原始信号
            gauss_noise = noise_generate(app); % 生成均匀白噪声
            sampling_time = 0:1 / app.sampling_rate.Value:(app.NPT - 1) / app.sampling_rate.Value; % 采样时间的计算
            % 开始进行信号的累加
            if app.activate_1.Value
                raw_signal = raw_signal + app.Vpp_num_1.Value * sin(2 * pi * app.Freq_num_1.Value * sampling_time + app.Phase_num_1.Value / 180 * pi); % 计算信号累加
            end
            if app.activate_2.Value
                raw_signal = raw_signal + app.Vpp_num_2.Value * sin(2 * pi * app.Freq_num_2.Value * sampling_time + app.Phase_num_2.Value / 180 * pi); % 计算信号累加
            end
            if app.activate_3.Value
                raw_signal = raw_signal + app.Vpp_num_3.Value * sin(2 * pi * app.Freq_num_3.Value * sampling_time + app.Phase_num_3.Value / 180 * pi); % 计算信号累加
            end
            if app.activate_4.Value
                raw_signal = raw_signal + app.Vpp_num_4.Value * sin(2 * pi * app.Freq_num_4.Value * sampling_time + app.Phase_num_4.Value / 180 * pi); % 计算信号累加
            end
            if app.activate_5.Value
                raw_signal = raw_signal + app.Vpp_num_5.Value * sin(2 * pi * app.Freq_num_5.Value * sampling_time + app.Phase_num_5.Value / 180 * pi); % 计算信号累加
            end
            if app.activate_6.Value
                raw_signal = raw_signal + app.Vpp_num_6.Value * sin(2 * pi * app.Freq_num_6.Value * sampling_time + app.Phase_num_6.Value / 180 * pi); % 计算信号累加
            end
            raw_signal = raw_signal + gauss_noise; % 最后加上高斯白噪声
        end
        
        function noise_array = noise_generate(app)
            % 评价是高斯白噪声不如均匀随机噪声
            % 高斯白噪声
%             rng('default'); % 恢复至初始状态
%             noise_array = normrnd(0,app.noise_knob.Value / 2 / 3,1,app.NPT); % 得到高斯白噪声
            %均匀随机噪声
            noise_array = rand(1,app.NPT);
            noise_max = app.noise_knob.Value;
            noise_array = (noise_array .* 2 .* noise_max - noise_max) / 1000;
            output_remain(app,'噪声生成成功');
            noise_str = sprintf('噪声大小峰值为:%.4fmV',noise_max);
            output_remain(app,noise_str);
        end
        
        % 给信号进行加窗处理
        function signal_new = window_add(app,signal)
            if app.APFFTButton.Value
                output_remain(app,'APFFT:开启');
                % 需要对两个窗进行卷积处理
                % 第一个窗
                switch (app.window_weight_1.Value)
                case 'Rectangle'
                    window_1 = ones(1,length(signal) / 2); % 矩形窗不处理
                case 'Triangle'
                    window_1 = triang(length(signal) / 2)'; % 三角窗
                case 'Hanning'
                    window_1 = hann(length(signal) / 2)'; % 汉宁窗
                case 'Hamming'
                    window_1 = hamming(length(signal) / 2)'; % 汉明窗
                case 'Kaiser'
                    window_1 = kaiser(length(signal) / 2,app.window_num_1.Value)'; % 凯泽窗(不指定参数时则默认为0.5)
                case 'Blackman'
                    window_1 = blackman(length(signal) / 2)'; % 布莱克曼窗
                case 'FlatTop'
                    window_1 = flattopwin(length(signal) / 2)'; % 平顶窗
                case 'Gauss'
                    window_1 = gausswin(length(signal) / 2)'; % 高斯窗
                case 'Chebyshev'
                    window_1 = chebwin(length(signal) / 2,app.window_num_1.Value)'; % 切比雪夫窗(不指定则默认衰减100dB)
                end
                % 第二个窗
                switch (app.window_weight_2.Value)
                case 'Rectangle'
                    window_2 = ones(1,length(signal) / 2); % 矩形窗不处理
                case 'Triangle'
                    window_2 = triang(length(signal) / 2)'; % 三角窗
                case 'Hanning'
                    window_2 = hann(length(signal) / 2)'; % 汉宁窗
                case 'Hamming'
                    window_2 = hamming(length(signal) / 2)'; % 汉明窗
                case 'Kaiser'
                    window_2 = kaiser(length(signal) / 2,app.window_num_1.Value)'; % 凯泽窗(不指定参数时则默认为0.5)
                case 'Blackman'
                    window_2 = blackman(length(signal) / 2)'; % 布莱克曼窗
                case 'FlatTop'
                    window_2 = flattopwin(length(signal) / 2)'; % 平顶窗
                case 'Gauss'
                    window_2 = gausswin(length(signal) / 2)'; % 高斯窗
                case 'Chebyshev'
                    window_2 = chebwin(length(signal) / 2,app.window_num_1.Value)'; % 切比雪夫窗(不指定则默认衰减100dB)
                end
                window_weight = conv(window_1,window_2); % 进行双窗卷积
                window_weight = [0 window_weight]; % 卷积之后的长度差一位，这是延长数组
                signal_new = signal .* window_weight; % 进行窗函数的加权
                signal_new = signal_new(1:length(signal) / 2) + signal_new(length(signal) / 2 + 1:end); % APFFT需要长度减半的预处理，不能够直接加上卷积窗
                output_remain(app,'窗函数加权完成');
                plot(app.Window_weight_map,0:length(signal_new) * 2 - 1,window_weight,'k'); % 绘制窗函数权值图
                output_remain(app,'窗函数权值图像绘制完成');
            else
            % 窗函数的选择
            output_remain(app,'APFFT:关闭');
            switch (app.window_weight_1.Value)
                case 'Rectangle'
                    window_weight = ones(1,length(signal)); % 矩形窗不处理
                    output_remain(app,'窗函数:矩形窗');
                case 'Triangle'
                    window_weight = triang(length(signal))'; % 三角窗
                    output_remain(app,'窗函数:三角窗');
                case 'Hanning'
                    window_weight = hann(length(signal))'; % 汉宁窗
                    output_remain(app,'窗函数:汉宁窗');
                case 'Hamming'
                    window_weight = hamming(length(signal))'; % 汉明窗
                    output_remain(app,'窗函数:汉明窗');
                case 'Kaiser'
                    if app.window_num_1.Value == 0
                        window_weight = kaiser(length(signal))';
                    else
                        window_weight = kaiser(length(signal),app.window_num_1.Value)'; % 凯泽窗(不指定参数时则默认为0.5)
                    end
                    output_remain(app,'窗函数:凯瑟窗');
                case 'Blackman'
                    window_weight = blackman(length(signal))'; % 布莱克曼窗
                    output_remain(app,'窗函数:布莱克曼窗');
                case 'FlatTop'
                    window_weight = flattopwin(length(signal))'; % 平顶窗
                    output_remain(app,'窗函数:平顶窗');
                case 'Gauss'
                    window_weight = gausswin(length(signal))'; % 高斯窗
                    output_remain(app,'窗函数:高斯窗');
                case 'Chebyshev'
                    if app.window_num_1.Value == 0
                        window_weight = chebwin(length(signal))'; % 切比雪夫窗(不指定则默认衰减100dB)
                    else
                        window_weight = chebwin(length(signal),app.window_num_1.Value)'; % 切比雪夫窗(不指定则默认衰减100dB)
                    end
                    output_remain(app,'窗函数:切比雪夫窗');
            end
            signal_new = signal .* window_weight; % 进行窗函数的加权
            output_remain(app,'窗函数加权完成');
            plot(app.Window_weight_map,0:length(signal_new) - 1,window_weight,'k'); % 绘制窗函数权值图
            end
        end
        
        % 信号的单线性插值
        function insert_signal = signal_insert(app,signal)
            insert_signal = zeros(1,2 * app.NPT);
            insert_signal(1,1:2) = signal(1,1); % 第一，二个值暂且不做插值
            for p = 2:str2double(app.FFT_point_knob.Value)
                insert_signal(1,2 * p) = signal(1,p);
                insert_signal(1,2 * p - 1) = (signal(1,p) + signal(1,p - 1)) / 2;
            end
        end
        
        function fft_cal(app,signal)
            output_remain(app,'开始计算FFT');
            fft_raw = fft(signal); % 计算FFT
            fft_abs = abs(fft_raw); % 计算模值
            fft_angle = angle(fft_raw); % 计算相位
            output_remain(app,'计算完成');
            % 画图
            plot(app.Freq_map,0:length(signal) - 1,fft_abs);
            output_remain(app,'频谱绘制完成');
            stem(app.Phase_map,0:length(signal) - 1,fft_angle / pi * 180);
            output_remain(app,'相位谱绘制完成');
        end
        
        % 输出文本函数
        function output_remain(app,str)
            output_string = sprintf("%s",str);
            app.output_string_buff = horzcat(app.output_string_buff,output_string);
            app.output_text.Value = app.output_string_buff;
        end
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Calculate_begin
        function Calculate_beginButtonPushed(app, event)
            app.output_text.Value = '程序正常，准备进行运算';
            app.output_string_buff = '程序正常，准备进行运算'; % 清空输出缓冲
            app.NPT = str2double(app.FFT_point_knob.Value); % FFT点数 
            user_main(app); % 进入自定义的main函数
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Freq_map = uiaxes(app.UIFigure);
            title(app.Freq_map, '频谱图')
            xlabel(app.Freq_map, '采样点')
            ylabel(app.Freq_map, '模值')
            zlabel(app.Freq_map, 'Z')
            app.Phase_map = uiaxes(app.UIFigure);
            title(app.Phase_map, '相位谱')
            xlabel(app.Phase_map, '采样点')
            ylabel(app.Phase_map, '相位')
            zlabel(app.Phase_map, 'Z')
            app.Raw_signal_map = uiaxes(app.UIFigure);
            title(app.Raw_signal_map, '原始信号')
            xlabel(app.Raw_signal_map, '采样点')
            ylabel(app.Raw_signal_map, '幅值')
            zlabel(app.Raw_signal_map, 'Z')
            app.Window_weight_map = uiaxes(app.UIFigure);
            title(app.Window_weight_map, '窗函数权值图')
            xlabel(app.Window_weight_map, '采样点')
            ylabel(app.Window_weight_map, '权重')
            zlabel(app.Window_weight_map, 'Z')
            app.Calculate_begin = uibutton(app.UIFigure, 'push');
            app.Calculate_begin.ButtonPushedFcn = createCallbackFcn(app, @Calculate_beginButtonPushed, true);
            app.Calculate_begin.Text = '计算';
            app.VEditFieldLabel = uilabel(app.UIFigure);
            app.VEditFieldLabel.Text = '幅值/V';
            app.Vpp_num_1 = uieditfield(app.UIFigure, 'numeric');
            app.Vpp_num_1.Value = 5;
            app.VEditField_2Label = uilabel(app.UIFigure);
            app.VEditField_2Label.Text = '幅值/V';
            app.Vpp_num_2 = uieditfield(app.UIFigure, 'numeric');
            app.VEditField_3Label = uilabel(app.UIFigure);
            app.VEditField_3Label.Text = '幅值/V';
            app.Vpp_num_3 = uieditfield(app.UIFigure, 'numeric');
            app.VEditField_4Label = uilabel(app.UIFigure);
            app.VEditField_4Label.Text = '幅值/V';
            app.Vpp_num_4 = uieditfield(app.UIFigure, 'numeric');
            app.HzEditFieldLabel = uilabel(app.UIFigure);
            app.HzEditFieldLabel.Text = '频率/Hz';
            app.Freq_num_1 = uieditfield(app.UIFigure, 'numeric');
            app.Freq_num_1.Value = 100;
            app.HzEditField_2Label = uilabel(app.UIFigure);
            app.HzEditField_2Label.Text = '频率/Hz';
            app.Freq_num_2 = uieditfield(app.UIFigure, 'numeric');
            app.HzEditField_3Label = uilabel(app.UIFigure);
            app.HzEditField_3Label.Text = '频率/Hz';
            app.Freq_num_3 = uieditfield(app.UIFigure, 'numeric');
            app.HzEditField_4Label = uilabel(app.UIFigure);
            app.HzEditField_4Label.Text = '频率/Hz';
            app.Freq_num_4 = uieditfield(app.UIFigure, 'numeric');
            app.VEditField_5Label = uilabel(app.UIFigure);
            app.VEditField_5Label.Text = '幅值/V';
            app.Vpp_num_5 = uieditfield(app.UIFigure, 'numeric');
            app.VEditField_6Label = uilabel(app.UIFigure);
            app.VEditField_6Label.Text = '幅值/V';
            app.Vpp_num_6 = uieditfield(app.UIFigure, 'numeric');
            app.HzEditField_5Label = uilabel(app.UIFigure);
            app.HzEditField_5Label.Text = '频率/Hz';
            app.Freq_num_5 = uieditfield(app.UIFigure, 'numeric');
            app.HzEditField_6Label = uilabel(app.UIFigure);
            app.HzEditField_6Label.Text = '频率/Hz';
            app.Freq_num_6 = uieditfield(app.UIFigure, 'numeric');
            app.EditFieldLabel = uilabel(app.UIFigure);
            app.EditFieldLabel.Text = '相位/角度';
            app.Phase_num_1 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_2Label = uilabel(app.UIFigure);
            app.EditField_2Label.Text = '相位/角度';
            app.Phase_num_2 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_3Label = uilabel(app.UIFigure);
            app.EditField_3Label.Text = '相位/角度';
            app.Phase_num_3 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_4Label = uilabel(app.UIFigure);
            app.EditField_4Label.Text = '相位/角度';
            app.Phase_num_4 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_5Label = uilabel(app.UIFigure);
            app.EditField_5Label.Text = '相位/角度';
            app.Phase_num_5 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_6Label = uilabel(app.UIFigure);
            app.EditField_6Label.Text = '相位/角度';
            app.Phase_num_6 = uieditfield(app.UIFigure, 'numeric');
            app.activate_1 = uibutton(app.UIFigure, 'state');
            app.activate_1.Text = '启用';
            app.activate_1.Value = true;
            app.activate_3 = uibutton(app.UIFigure, 'state');
            app.activate_3.Text = '启用';
            app.activate_4 = uibutton(app.UIFigure, 'state');
            app.activate_4.Text = '启用';
            app.activate_5 = uibutton(app.UIFigure, 'state');
            app.activate_5.Text = '启用';
            app.activate_6 = uibutton(app.UIFigure, 'state');
            app.activate_6.Text = '启用';
            app.activate_2 = uibutton(app.UIFigure, 'state');
            app.activate_2.Text = '启用';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '窗函数';
            app.window_weight_1 = uidropdown(app.UIFigure);
            app.window_weight_1.Items = {'Rectangle', 'Triangle', 'Hanning', 'Hamming', 'Kaiser', 'Blackman', 'FlatTop', 'Gauss', 'Chebyshev'};
            app.window_weight_1.Value = 'Rectangle';
            app.APFFTButton = uibutton(app.UIFigure, 'state');
            app.APFFTButton.Text = 'APFFT';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '输出内容';
            app.output_text = uitextarea(app.UIFigure);
            app.output_text.Editable = 'off';
            app.mVLabel = uilabel(app.UIFigure);
            app.mVLabel.Text = '随机噪声峰值/mV';
            app.noise_knob = uiknob(app.UIFigure, 'continuous');
            app.FFTKnobLabel = uilabel(app.UIFigure);
            app.FFTKnobLabel.Text = 'FFT点数';
            app.FFT_point_knob = uiknob(app.UIFigure, 'discrete');
            app.FFT_point_knob.Items = {'64', '128', '256', '512', '1024', '2048', '4096'};
            app.FFT_point_knob.Value = '4096';
            app.EditField_7Label = uilabel(app.UIFigure);
            app.EditField_7Label.Text = '窗函数系数';
            app.window_num_1 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_8Label = uilabel(app.UIFigure);
            app.EditField_8Label.Text = '窗函数系数';
            app.window_num_2 = uieditfield(app.UIFigure, 'numeric');
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = '窗函数';
            app.window_weight_2 = uidropdown(app.UIFigure);
            app.window_weight_2.Items = {'Rectangle', 'Triangle', 'Hanning', 'Hamming', 'Kaiser', 'Blackman', 'FlatTop', 'Gauss', 'Chebyshev'};
            app.window_weight_2.Value = 'Rectangle';
            app.single_liner_insert = uibutton(app.UIFigure, 'state');
            app.single_liner_insert.Text = '单线性插值';
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = '采样率';
            app.sampling_rate = uieditfield(app.UIFigure, 'numeric');
            app.sampling_rate.Value = 1000;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FFT

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end